classdef tDAQpy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetDAQUI                  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SetupTab                     matlab.ui.container.Tab
        DAQConfigurationPanel        matlab.ui.container.Panel
        DAQDeviceDropDownLabel       matlab.ui.control.Label
        DAQDeviceDropDown            matlab.ui.control.DropDown
        ScanRateLabel                matlab.ui.control.Label
        scanRateField                matlab.ui.control.NumericEditField
        SampleTimeLabel              matlab.ui.control.Label
        sampleTimeField              matlab.ui.control.NumericEditField
        SubjectInformationPanel      matlab.ui.container.Panel
        ParticipantIDEditFieldLabel  matlab.ui.control.Label
        ParticipantIDField           matlab.ui.control.EditField
        ProtocolEditFieldLabel       matlab.ui.control.Label
        ProtocolField                matlab.ui.control.EditField
        NotesTextAreaLabel           matlab.ui.control.Label
        NotesField                   matlab.ui.control.TextArea
        SaveConfigButton             matlab.ui.control.Button
        LoadConfigButton             matlab.ui.control.Button
        EMGOptionsPanel              matlab.ui.container.Panel
        CollectEMGCheckBox           matlab.ui.control.CheckBox
        ofEMGsSpinnerLabel           matlab.ui.control.Label
        nEMGSpinner                  matlab.ui.control.Spinner
        InitializeDAQButton          matlab.ui.control.Button
        FileOptionsPanel             matlab.ui.container.Panel
        CurrentDirectoryButton       matlab.ui.control.Button
        CurrentDirectoryTextArea     matlab.ui.control.TextArea
        basefilenameLabel            matlab.ui.control.Label
        basefilenameEditField        matlab.ui.control.EditField
        TransformTab                 matlab.ui.container.Tab
        TransformTable               matlab.ui.control.Table
        TransformTableLabel          matlab.ui.control.Label
        BonyLandmarkTable            matlab.ui.control.Table
        BonyLandmarkTableLabel       matlab.ui.control.Label
        SetTargetTab                 matlab.ui.container.Tab
        TargetSettingsPanel          matlab.ui.container.Panel
        WrenchRangeLabel             matlab.ui.control.Label
        TargetLabel                  matlab.ui.control.Label
        WrenchLimitsTable            matlab.ui.control.Table
        TargetTable                  matlab.ui.control.Table
        VisualRangeLabel             matlab.ui.control.Label
        TargetAxesLimitsTable        matlab.ui.control.Table
        OpenTargetWindowButton       matlab.ui.control.StateButton
        ZeroLoadCellButton           matlab.ui.control.Button
        ShowTargetCheckBox           matlab.ui.control.CheckBox
        WrenchPlotsTab               matlab.ui.container.Tab
        WrenchAxes1                  matlab.ui.control.UIAxes
        WrenchAxes2                  matlab.ui.control.UIAxes
        WrenchAxes3                  matlab.ui.control.UIAxes
        WrenchAxes4                  matlab.ui.control.UIAxes
        WrenchAxes5                  matlab.ui.control.UIAxes
        WrenchAxes6                  matlab.ui.control.UIAxes
        EMGPlotsTab                  matlab.ui.container.Tab
        EMGAxes1                     matlab.ui.control.UIAxes
        EMGAxes2                     matlab.ui.control.UIAxes
        EMGAxes3                     matlab.ui.control.UIAxes
        EMGAxes4                     matlab.ui.control.UIAxes
        EMGAxes5                     matlab.ui.control.UIAxes
        EMGAxes6                     matlab.ui.control.UIAxes
        StopButton                   matlab.ui.control.Button
        TrialEditFieldLabel          matlab.ui.control.Label
        TrialNumberField             matlab.ui.control.NumericEditField
        StatusLabel                  matlab.ui.control.Label
        AcquireDataButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
         
        
        % Target Window Figure Handles
        FigureHandle                                    % handle to target figure
        AxesHandle                                      % handles to target axes
        
        % Patch objects 
        FBPyramidLH                                     % handle to feedback pyramid patch 
        TPyramidLH                                      % handle to target pyramid patch
        
        % Listener Handle
        DataAvailableLH                                 % listener handle for DAQ session 'DataAvailable' event
        
        % Transformation Matrices 
        CalMatrix = eye(6)                              % Load cell calibration matrix 
        BonyLandmarks = zeros(9,3)                      % landmark locations
        LC2Head = eye(6)                                % Transform matrix from Load Cell to Head
        LCZeroOffset = zeros(6,1)                       % load cell values from last time zero button pressed
        
        % Target and Feedback Pyramid Parameters
        pyramid_base = 1                                % width of pyramid base (pixels)
        pyramid_height = 5                              % height of pyramid peak (pixels)
        pyramid_vertices                                % base pyramid vertices for patch 
        pyramid_faces                                   % base pyramid faces for patch
        basecolor = [0 0.4470 0.7410]                   % dark blue = [0 0.4470 0.7410]
        sidecolor = [0.9290 0.6940 0.1250]              % yellow = [0.9290 0.6940 0.1250]
        designcolor = [0.3010 0.7450 0.9330]            % light blue = [0.3010 0.7450 0.9330]
        FBTransparency = 0.95                           % transparency of feedback pyramid faces
        TargetTransparency = 0.7                        % transparency of target pyramid faces [0 1] (0 = transparent)
        
        % Target Setting Values
        TargetValues = zeros(6,1);                      % 1x6 array of target direction [fx fy fz mx my mz]
        
        % Target Window Visuals
        drawtarget = 0                                  % boolean whether or not to draw target
        
        % DAQ Configuration 
        DevicesInfo                                     % array of device information 
        daqDevice = 'Dev1'                              % default device name 
        sRate = 1000                                    % default sampling rate (Hz)
        sTime = 10                                      % default sampling length (sec)
        
        % 
        DAQSession = []                                 % daq session from s = daqinit()
        
        % Line Handles 
        WrenchLH = []                                   % array of Line handles
        EMGLH = []                                      % array of Line handles    
        
        
        % Range for visual feedback
        VisLim = [  -100            100;        ...
                    -100            100;        ...
                     2               30;        ...
                   deg2rad(-45)  deg2rad(45);   ...
                   deg2rad(-45)  deg2rad(45);   ...
                   deg2rad(-45)  deg2rad(45)]           % (6x2) (x,y,z,tx,ty,tz) x (min max)        
        
        % Wrench Range
        WRanges = [ -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30]                             % (6x2) (Fx,Fy,Fz,Mx,My,Mz) by (min max) in N or Nm
        
        % Axes Handles
        WrenchAxes = []                                 % array of uiAxis handles 
        EMGAxes = []                                    % array of uiAxis handles 
        
        % Buffers
        TimestampsFIFOBuffer = []                       % Timestamps FIFO buffer of latest N sec
        RawDataFIFOBuffer = []                          % Raw voltage data from DAQ 
        WrenchFIFOBuffer = []                           % Wrench Data FIFO buffer (ch x n)
        EMGFIFOBuffer = []                              % EMG DATA FIFO buffer (ch x n)
        FIFOMaxSize = 1E+5                              % Max allowed buffer size
        
        % Configuration Information 
        Date = []                                       % saved date
        datadir = cd                                    % Description
        subjID = []                                     % 
        Protocol = []                                   % 
        Notes = []                                      %
        
        WrenchChannels = 0:5                            % daq channels for load cell inputs
        EMGChannelsAvail = 6:11                         % daq channels available for EMG inputs
        EMGChannels = []                                % daq channels for EMG inputs
        WrenchChannelNames = {'Fx','Fy','Fz','Mx','My','Mz'}
        EMGChannelNames = {'EMG1','EMG2','EMG3','EMG4','EMG5','EMG6'}
        
        % filepaths
        CalMatPath = jr3_loadcell.cal_mat                  % Calibration Matrix 
        
        % settings
        collectEMG = 0                                  % boolean: 0 = don't collect EMG; 1 = collect EMG
        
        isAcquiring = 0                                 % boolean: 0: not acquiring data, 1: acquiring data
        
        itrial = 1                                      % 
        filename = 'data_trial'                         % 
        
        logdata = 0                                     % boolean: 0: don't save data; 1: save data to file
        starttime = []                                  % timestamp of first logged entry, set to empty if not logging
        TempFilename
        TempFile                                        % Temporary 
        TotalDataSaved                                  % Description

    end
    
    
    methods (Access = private)       
    
        % Transform data to new scale based on [min max] 
        function new_data = changeScale(~, data, iR, oR)
            % Inputs:       iR: (nx2) input Range n rows of [min max] 
            %               oR: (nx2) output Range n rows of [min max]
            %               data: (nx1) vector of data in input range (corresponding to [min max] in nth row)
            % Outputs:      new_data: (nx1) vector of data in output range
            % 
                       
            new_data = (oR(:,2)-oR(:,1))./(iR(:,2)-iR(:,1)).*(data - iR(:,1)) + oR(:,1);    
        end

        
        % homogenous (4x4) transformation matrix to:
        % rotate around arbitrary axis u (1x3) by th (scaler) in radians
        function R = rotateAxis(~, u, th)
            % u: unit vector, arbitrary axis to rotate around
            % th: angle in radians to rotate about u
            % R : 3x3 rot mat
            
            R = eye(3);
            nu = norm(u);
            eps = 0.01; % sensitivity for detecting rotation direction 
            if nu > eps % if not a zero vector
                % make sure it is a unit vector
                u = u/nu;
                % trig
                s = sin(th);
                c = cos(th);
                % Rodriguez formula
                for ii=1:3
                    v = R(:,ii);
                    R(:,ii) = v*c + cross(u,v)*s + u*dot(u,v)*(1-c);                      
                end                
            end          
        end
    
        % homogenous (4x4) transformation matrix to: 
        % rotate by R (3x3) and translate by t (1x3) or (3x1)
        function Tmat = TransformMat(~,R,t)
            Tmat = [R [t(1) t(2) t(3)]'; [0 0 0 1]];
        end
        
        % homogenous (4x4) transformation matrix to:
        % scale by s (scalar) uniformly about origin
        function Tmat = scaleMat(~,s)            
            Tmat = [s*eye(3) zeros(3,1); [0 0 0 1]];            
        end
    
        function pts = hom(~, pts) 
            % adds additional ROW of ones
            npts = size(pts,2);
            pts = [pts; ones(1,npts)];
        end
        
        function pts = unhom(~, pts)
            % removes last ROW
            pts = pts(1:3,:);
        end
    
        % vertices and faces for a pyramid 
        % to be used to create a patch object
        function [v, f, c] = pyramid(~, bw, ht, base_color, side_color, design_color)
            % bw: base width 
            % ht: height on z axis 
            % v : vertices (x, y, z)
            % f : faces by vertex index 
            % c : array of face colors by face number
            
            bp = bw/2;
            
            %pyramid vertices (8) (need to transpose for hom, unhom, and computations)
            v =     [    bp  -bp    0  ;... %1
                        -bp  -bp    0  ;... %2
                        -bp   bp    0  ;... %3
                         bp   bp    0  ;... %4
                         0    0    -ht ;... %5
                         0    0     0 ];... %6
        
            % pyramid faces (8)
            f = [   3 4 6 3  ; ...% base top
                    4 1 6 4  ; ...% base right
                    1 2 6 1  ; ...% base bottom
                    2 3 6 2  ; ...% base left
                    3 4 5 3  ; ...% side top
                    4 1 5 4  ; ...% side right
                    1 2 5 1  ; ...% side bottom
                    2 3 5 2 ]; ...% side left 
                    
            % face colors (8)
            c = [  repmat(design_color, 1,1); ... % top base triangle
                   repmat(base_color,   3,1); ... % rest of base
                   repmat(side_color,   4,1) ];   % sides

        end
    
        % update or create patch object of pyramid using 6x1 vec (x,y,z,thx,thy,thz) 
        function lh = updatePyramid(app, lh, p, r, id) 
            % lh: patch object 
            % p: (3x1) or (1x3) linear components
            % f: (3x1) or (1x3) rotational components
            
            
            % if the line handle has not been created
            if isempty(lh)
                % get vertices and faces for base pyramid at [0, 0, 0, 0, 0, 0]
                [app.pyramid_vertices, app.pyramid_faces, c] = app.pyramid(app.pyramid_base, app.pyramid_height, app.basecolor, app.sidecolor, app.designcolor);

                % create patch 
                lh = patch(app.AxesHandle);
                lh.Vertices = app.pyramid_vertices;
                lh.Faces = app.pyramid_faces;                
                lh.FaceColor = 'flat';
                lh.FaceVertexCData = c;
                
                switch id
                    case {'fb',1}
                        lh.FaceAlpha = app.FBTransparency;
                    case {'target',2}
                        lh.FaceAlpha = app.TargetTransparency;
                    otherwise
                end
                
            end
            
            % Transform Matrices
            R = app.rotateAxis(r,norm(r));
            gRxy = app.TransformMat(R,[p(1:2); 0]);
            gz = app.scaleMat(p(3));
            Tmat = gRxy*gz;
            
            % transform original pyramid
            v = app.unhom(Tmat*app.hom(app.pyramid_vertices'))';
            
            % set circle target line handle data to these new points
            lh.Vertices = v;
         
        end   


        function new_value = deleteHandle(~,handle)
            % delete handle and set value to empty
            if ~isempty(handle)
                for ii = 1 : length(handle) % single or list of handles
                    if ishandle(handle(ii))
                        delete(handle(ii))
                    end                    
                end
                new_value = []; % set handle to empty
            else % if already empty
                new_value = handle; % pass through
            end
            
        end
    
        
        function new_value = closeFigure(~,handle)
            % close figure window and delete handle
            if ~isempty(handle)
                if ishandle(handle)
                    close(handle) % executes figure's CloseRequestFcn
                end
                new_value = [];
            else
                new_value = handle;
            end         
            
        end 
    
        function lh = clearLine(~, lh)
            % This clears the data in the line handle but does not delete the handle 
            for ii = 1 : length(lh)
                if ishandle(lh(ii))
                    set(lh(ii), 'XData', [],'YData', [])
                end
            end
        end
        
        function initializeTargetWindow(app)
            
            % Create figure
            app.FigureHandle = figure('Name','Target DAQ'...
                ,'Resize','on','MenuBar','none');
            
            % Create axes
            app.AxesHandle = axes(app.FigureHandle...
                ,'XTick',[],'YTick',[],'XTickLabel',{},'YTickLabel',{}...
                ,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1]);
                
            % add title to Axes
            app.AxesHandle.Title.String = 'Target Display';                        
                                                    
            % Set x and y axis limits 
            app.AxesHandle.XLim = app.VisLim(1,:);
            app.AxesHandle.YLim = app.VisLim(2,:);
            %app.AxesHandle.ZLim = app.VisLim(3,:);
            
            % make axis visible
            app.AxesHandle.XAxisLocation = 'origin';
            app.AxesHandle.YAxisLocation = 'origin';
            app.AxesHandle.XAxis.Visible = 'on';
            app.AxesHandle.YAxis.Visible = 'on';
            app.AxesHandle.XAxis.LineWidth = 1.0;
            app.AxesHandle.YAxis.LineWidth = 1.0;
            app.AxesHandle.XAxis.Color = 'b';
            app.AxesHandle.YAxis.Color = 'g';  
            
        end
      
        function lh = initializePlots(~, ax, ch, ch_names)
            % ax = app.WrenchAxes or app.EMGAxes; % (1 x #ch) array of UIAxis handles
            % ch = app.WrenchChannels or app.EMGChannels; % (1 x #ch) array of daq channels
            % lh : (1 x #ch) array of line handles 
            
            nCh = length(ch);
            lh = [];                      
                
            % Create a line plot and store its handle
            for ii = 1 : nCh
                title(ax(ii),ch_names(ii))
                lh(ii) = plot(ax(ii), NaN, NaN);                
            end
           
        end    

    
        function data = storeDataInFIFO(~, data, buffersize, datablock)
            % storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
            %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
            %  a smooth live plot update and without continuously increasing memory use.
            %  The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the 
            %  buffer exceeds the specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of
            %  the data in the buffer to BUFFERSIZE.
            %  input DATA is the existing data buffer (array nchan x N).
            %  BUFFERSIZE is the desired buffer size (maximum number of columns in DATA buffer) and can be changed.
            %  DATABLOCK is a new data block to be added to the buffer (array nchan x K).
            %  output DATA is the updated data buffer (array nchan x M).
            
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
                
            % length of data and data block (number of samples)
            ndata = size(data,2);
            ndatablock = size(datablock,2);
            
            if ndata > buffersize
                data = data(:,end-buffersize+1:end);
            end
            
            if ndatablock < buffersize
                % Data block size (number of columns) is smaller than the buffer size
                if ndata == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = ndatablock;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,end-shiftPosition+1:end) = datablock;
                elseif (ndata < buffersize) && (ndata + ndatablock > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data datablock];
                    shiftPosition = ndata - buffersize;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,buffersize+1:end) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(:,end-buffersize+1:end);
            end            
        end

        function DataAvailableCallback(app, ~, event)
            % dataAvailable_Callback Executes on DAQ session DataAvailable event
            %  This callback function gets executed periodically as more data is acquired.
            %  event.TimeStamps (nx1) and event.Data (n x nch)         
            
            % organize data and store in FIFO buffers 
            app.RawDataFIFOBuffer = app.storeDataInFIFO(app.RawDataFIFOBuffer, app.FIFOMaxSize, event.Data');
            
            timestamps = event.TimeStamps'; % make (1 x #scans)
            app.TimestampsFIFOBuffer = app.storeDataInFIFO(app.TimestampsFIFOBuffer, app.FIFOMaxSize, timestamps);
            
            wrenchdata = app.CalMatrix * (event.Data(:,app.WrenchChannels+1)' - app.LCZeroOffset); % (#ch x #scans)
            app.WrenchFIFOBuffer = app.storeDataInFIFO(app.WrenchFIFOBuffer, app.FIFOMaxSize, wrenchdata);
            
            if ~isempty(app.EMGChannels)
                emgdata = event.Data(:,app.EMGChannels+1)'; % (#ch x #scans)
                app.EMGFIFOBuffer = app.storeDataInFIFO(app.EMGFIFOBuffer, app.FIFOMaxSize, emgdata);
            else
                emgdata = [];
            end
            
            % log data
            if app.logdata
                
                if isempty(app.starttime)
                    % set start time to first time stamp 
                    app.starttime = timestamps(1); 
                    app.TotalDataSaved = 0;
                end               
                
                % save to temp file 
                fwrite(app.TempFile, squeeze([timestamps;wrenchdata;emgdata]), 'double');
                
                % update count of data (used to keep track of time for end condition)
                app.TotalDataSaved = app.TotalDataSaved + length(timestamps);
                
                % check ending condition
                if app.TotalDataSaved >= app.sTime * app.sRate
                    % stop logging data
                    app.logdata = 0;
                    
                    % close temp file and save to .mat file 
                    app.saveDataFile(app.starttime, timestamps(end), app.TotalDataSaved);
                    
                    % enable buttons now that finished acquiring
                    app.EnableDisableAcquisition(1)
                    
                    % reset 
                    app.starttime = [];
                    app.TotalDataSaved = 0;  
                    
                    % update trial number for next trial
                    % update trial number 
                    app.itrial = app.itrial + 1;
                    app.TrialNumberField.Value = app.itrial;
                end             
                
            end
            
            % Update plot data if in current view
            if app.TabGroup.SelectedTab == app.WrenchPlotsTab
                app.updatePlots(app.WrenchAxes, app.WrenchLH, app.TimestampsFIFOBuffer, app.WrenchFIFOBuffer);
            end
            if app.TabGroup.SelectedTab == app.EMGPlotsTab && app.collectEMG == 1 && ~isempty(app.EMGChannels)
                app.updatePlots(app.EMGAxes, app.EMGLH, app.TimestampsFIFOBuffer, app.EMGFIFOBuffer);
            end            
            
            % update target window if open too 
            if ishandle(app.FigureHandle)
                % transform wrench data for visual feedback in target display
                meandata = mean(wrenchdata,2);
                fbdata = app.changeScale(meandata, app.WRanges, app.VisLim); % (6x6) -> (6x1)
                app.FBPyramidLH = app.updatePyramid(app.FBPyramidLH, fbdata(1:3), fbdata(4:6), 'fb');
                
                %%%% debug %%%%
                debug = 0;
                if debug == 1
                    disp('fbdata is:')
                    disp(fbdata)
                end               
                %%%% end debug %%%%                
                
            end          
        end        

    
        function binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % MAT file (filenameOut) is a MAT file with the following variables
        %   timestamps = a column vector ,  the first column in the data from binary file
        %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
        %   metatada = a structure, which is provided as input argument, used to provide additional
        %              data information
        %
            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,numColumns-1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), :) = data(2:end,:)'; % error here
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
        end
    
    
        function saveDataFile(app, time0, timef, nScans)
            
            % Log data to file switch is on
            % Save logged data to MAT file (unless the user clicks Cancel in the "Save As" dialog)
            
            % Close temporary binary file
            fclose(app.TempFile);
            
            
            % Gather metadata in preparation for saving to MAT file
            % Store relevant Daq device info
            metadata = app.setupStruct();
            metadata.trialnum = app.itrial;  
            metadata.time0 = time0;
            metadata.timef = timef;
            metadata.nScans = nScans;


            % add trial number to filename
            [~, fn, ~] = fileparts(app.filename);            
            new_fn = [fn '_' num2str(app.itrial) '.mat'];
            matFilepath = fullfile(app.datadir, new_fn);
            % if file exists, add _N to end until unique
            matFilepath = app.uniqueFileName(matFilepath, '.mat');
            
            app.StatusLabel.Text = 'Saving data to MAT file is in progress...';
            drawnow
            
            numColumns = length(squeeze([app.WrenchChannels app.EMGChannels])) + 1; %nchannels + 1 for timestamps
            
            % Convert data from binary file to MAT file
            app.binFile2MAT(app.TempFilename, matFilepath, numColumns, metadata);
            
            % take just the filename for status update
            [~, fn, ext] = fileparts(matFilepath); 
            app.StatusLabel.Text = sprintf('Saving data to ''%s'' file has completed.', [fn ext]);
            
        end
            

        function updatePlots(~, ax, lh, xdata, ydata)
            % ax = app.WrenchAxes or app.EMGAxes; %(1 x #Axes) array of UIAxis handles
            % xdata = app.TimestampsFIFOBuffer; % (1 x #scans) array
            % ydata = app.WrenchFIFOBuffer or app.EMGFIFOBuffer; % (#ch x #scans) array
            
            % set axis data objects
            if numel(xdata) > 1
                for ii = 1 : length(lh)
                    if ishandle(lh(ii))
                        set(lh(ii), 'XData', xdata, 'YData', ydata(ii,:));
                    end
                    if ishandle(ax(ii))
                        xlim(ax(ii), [xdata(1), xdata(end)]); 
                    end                        
                end
            end
            
        end    
       
        function cal_mat = loadCalMatrix(~,mat)

            if ismatrix(mat) 
                cal_mat = mat;               
            else 
                % If error, just use identity matrix is being used 
                cal_mat = eye(6);
                warndlg('Could not find calibration matrix. Using identity matrix instead.','Calibration Matrix');
            end

            
        end
        
        function closeApp_Callback(app, ~, event, isAcquiring)
            %closeApp_Callback Executes after "Close Confirm" dialog window
            %  "Close Confirm" dialog window is launched from DataAcquisitionLiveCloseRequest.
            %   before closing app if acquisition is currently on (isAcquiring=true) clean-up 
            %   DAQ session and close file if logging.
            
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession);
                        app.deleteHandle(app.DataAvailableLH);
                        app.deleteHandle(app.DAQSession);
                        %if app.LogRequested
                        %    fclose(app.TempFile);
                        %end
                    else
                        % Acquisition is stopped
                    end
                    
                    % Re-enable warning when session rate is adjusted to closest supported sampling rate
                    warning('on', 'daq:Session:closestRateChosen')
                    delete(app);
                case 'Cancel'
                    % Continue
            end
            
        end


        function EnableDisableInitialization(app, bool)
           
            switch bool 
               
                case 0 % DAQ initialized and Background Collection Running                  
                    app.InitializeDAQButton.Enable = 'off';
                    app.scanRateField.Enable = 'off';
                    app.CollectEMGCheckBox.Enable = 'off';
                    app.nEMGSpinner.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.DAQDeviceDropDown.Enable = 'off';
                    app.AcquireDataButton.Enable = 'on';
                    app.LoadConfigButton.Enable = 'off';
                    
               
               case 1 % DAQ stopped and Cleared; not running 
                    app.InitializeDAQButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.scanRateField.Enable = 'on';
                    app.CollectEMGCheckBox.Enable = 'on';
                    app.nEMGSpinner.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.AcquireDataButton.Enable = 'off';
                    app.DAQDeviceDropDown.Enable = 'on';
                    app.LoadConfigButton.Enable = 'on';
                    
           end
        end
    
        function EnableDisableAcquisition(app, bool)
            
            switch bool
                case 0 % currently acquiring 
                    app.StatusLabel.Text = 'Status: Acquiring Data';
                    app.AcquireDataButton.Enable = 'off';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.sampleTimeField.Enable = 'off';
                case 1 % not acquiring data
                    app.AcquireDataButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.sampleTimeField.Enable = 'on';

            end
        end      


        function setup = setupStruct(app)

            setup.subject = struct('Date',date, 'subjID',app.subjID, 'Protocol',app.Protocol, 'Notes',app.Notes);
            setup.daq = struct('DevicesInfo', app.DevicesInfo, 'WrenchChannels',app.WrenchChannels,'EMGChannels',app.EMGChannels,...
                    'WrenchChannelNames',{app.WrenchChannelNames},'EMGChannelNames',{app.EMGChannelNames},'sRate',app.sRate,'sTime',app.sTime);
            setup.target = struct('TargetValues',app.TargetValues,'VisLim',app.VisLim, 'WRanges',app.WRanges);   
            setup.params = struct('LC2Head',app.LC2Head,'BonyLandmarks',app.BonyLandmarks,'CalMatrix',app.CalMatrix);            
            
        end
    
        function value = ifisfield(~, s, fstr)
            if isfield(s,fstr)
                value = s.(fstr);
            else
                value = [];
            end
        end
    
        function loadConfigUpdate(app, setup)
            
            % LOAD VALUES FROM SETUP STRUCT
            % setup.subject
            s = setup.subject;
            app.subjID = app.ifisfield(s,'subjID');
            app.Protocol = app.ifisfield(s,'Protocol');
            app.Notes = app.ifisfield(s,'Notes');
            % setup.daq
            s = setup.daq;
            app.WrenchChannels = app.ifisfield(s,'WrenchChannels');
            app.EMGChannels = app.ifisfield(s,'EMGChannels');
            app.WrenchChannelNames = app.ifisfield(s,'WrenchChannelNames');
            app.EMGChannelNames = app.ifisfield(s,'EMGChannelNames');
            app.sRate = app.ifisfield(s,'sRate');
            app.sTime = app.ifisfield(s,'sTime');
            % add nEMG
            % setup.target
            s = setup.target;
            app.TargetValues = app.ifisfield(s,'TargetValues');
            app.VisLim = app.ifisfield(s,'VisLim');
            app.WRanges = app.ifisfield(s,'WRanges');
            % setup.params
            s = setup.params;
            app.LC2Head = app.ifisfield(s,'LC2Head');
            app.BonyLandmarks = app.ifisfield(s,'BonyLandmarks');
            app.CalMatrix = app.ifisfield(s,'CalMatrix');
            
            % UPDATE UI COMPONENTS
            
            % subject
            app.ParticipantIDField.Value = app.subjID;
            app.ProtocolField.Value = app.Protocol;
            app.NotesField.Value = app.Notes;
            % daq
            app.scanRateField.Value = app.sRate;
            app.sampleTimeField.Value = app.sTime;
            % add nEMGspinner
            % target
            app.TargetTable.Data = app.TargetValues;
            app.TargetAxesLimitsTable.Data = [app.VisLim(1:3,:); rad2deg(app.VisLim(4:6,:))];
            app.WrenchLimitsTable.Data = app.WRanges;            
            % params
            app.TransformTable.Data = app.LC2Head;
            app.BonyLandmarkTable.Data = app.BonyLandmarks;
            
            % other dependent fields
            app.pyramid_base = app.VisLim(1,2)/10; % based on x-axis upper limit
            app.pyramid_height = app.VisLim(3,2)/4; % based on z-axis upper limit

            
        end
        
        function fullName = uniqueFileName(~, fullName, ext)
            % fullName includes the path (relative or not)
            % ext is the extension (ie '.mat')
            [pn, fn, ~] = fileparts(fullName);
            
            iter = 0;
            while isfile(fullName) % file exists
                iter = iter + 1;
                new_fn = [fn '_' num2str(iter) ext];
                fullName = fullfile(pn, new_fn);
            end
            
        end
    
        function initializeAppVars(app)
            
            % get calibration matrix 
            app.CalMatrix = app.loadCalMatrix(app.CalMatPath);
            
            % set to default values from ui control
            app.drawtarget = app.ShowTargetCheckBox.Value;
            app.collectEMG = app.CollectEMGCheckBox.Value;
            
            app.sRate = app.scanRateField.Value;
            app.sTime = app.sampleTimeField.Value;
            
            app.subjID = app.ParticipantIDField.Value;
            app.Protocol = app.ProtocolField.Value;
            app.Notes = app.NotesField.Value;          
            
            % uiAxes Handles
            app.WrenchAxes = [app.WrenchAxes1,app.WrenchAxes2,app.WrenchAxes3,...
                app.WrenchAxes4,app.WrenchAxes5,app.WrenchAxes6];
            app.EMGAxes = [app.EMGAxes1,app.EMGAxes2,app.EMGAxes3,...
                app.EMGAxes4,app.EMGAxes5,app.EMGAxes6];            
                
            % Target Axes 
            app.TargetAxesLimitsTable.Data = [app.VisLim(1:3,:); rad2deg(app.VisLim(4:6,:))]; 
            app.WrenchLimitsTable.Data = app.WRanges;
            
            % Target Values
            app.TargetTable.Data = app.TargetValues;
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this is run at start up 
            
            % Detect all connected devices
            devices = daq.getDevices;
            
            % Store DAQ device information into DevicesInfo app property
            app.DevicesInfo = devices;
            
            % populate the device drop down list with cell array of composite device names (ID + model) 
            deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
            app.DAQDeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DAQDeviceDropDown.ItemsData = 1:numel(devices)+1;  
            
            % initialize app. properties 
            app.initializeAppVars();             
            
            % update tables
            app.TransformTable.Data = app.LC2Head;
            app.BonyLandmarkTable.Data = app.BonyLandmarks;          
            
            % Current data directory
            app.CurrentDirectoryTextArea.Value = app.datadir;


        end

        % Value changed function: OpenTargetWindowButton
        function OpenTargetWindowButtonValueChanged(app, event)
            targetOpen = app.OpenTargetWindowButton.Value;
            
            switch targetOpen
                
                case 0 % Close Figure and Reset Objects
                    
                    % Change button text 
                    app.OpenTargetWindowButton.Text = 'Open Target Window';
                    
                    % Close Figure and delete handle 
                    app.FigureHandle = app.closeFigure(app.FigureHandle);
                    
                    % Delete other Handles 
                    app.AxesHandle = app.deleteHandle(app.AxesHandle);                    
                    app.FBPyramidLH = app.deleteHandle(app.FBPyramidLH);
                    app.TPyramidLH = app.deleteHandle(app.TPyramidLH);
                    
                    % reset checkboxes and disable 
                    app.ShowTargetCheckBox.Value = 0;
                    app.ShowTargetCheckBox.Enable = 'off';
                    app.drawtarget = 0;
                    
                    
                case 1 % Open Figure and Initialize Objects
                    
                    % Change button text 
                    app.OpenTargetWindowButton.Text = 'Close Target Window';
                    
                    % open figure and axes for wrench feedback 
                    app.initializeTargetWindow();                                     
                   
                    % Enable checkboxes
                    app.ShowTargetCheckBox.Value = 0;                    
                    app.ShowTargetCheckBox.Enable = 'on';   
                    app.drawtarget = 0;                    
                    
            end
            
        end

        % Cell edit callback: TargetTable
        function TargetSetValueChanged(app, event)
            
            % update target settings property                        
            app.TargetValues = app.TargetTable.Data;             
                       
            % update target feedback if feedback window is open
            if ishandle(app.FigureHandle) & app.drawtarget == 1
                tvec = app.changeScale(app.TargetValues, app.WRanges, app.VisLim);
                app.TPyramidLH = app.updatePyramid(app.TPyramidLH, tvec(1:3), tvec(4:6),'target');                            
            end
            
        end

        % Button pushed function: ZeroLoadCellButton
        function ZeroLoadCellButtonPushed(app, event)
            if ~isempty(app.DAQSession) % daq is initialized
                if app.DAQSession.IsRunning  % daq is acquiring data
                    if ~isempty(app.RawDataFIFOBuffer) % has acquired at least a some data
                        % set app property to current load cell data 
                        cdata = app.RawDataFIFOBuffer(app.WrenchChannels+1,:);
                        amt = size(cdata,2);
                        if amt > 50
                            LCData = mean(cdata(:,end-50:end),2);
                        else
                            LCData = mean(cdata,2);
                        end                        
                        app.LCZeroOffset = LCData;
                        
                        debug = 0;
                        if debug == 1
                            disp('LCOffset = ')
                            disp(LCData)
                        end
                        
                    end
                end

            end           

        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            
            deviceIndex = app.DAQDeviceDropDown.Value - 1;
            
            if deviceIndex > 0
                
                % disable/enable Buttons 
                app.EnableDisableInitialization(0);
                
                % Delete DAQ objects if they already exist to avoid errors            
                app.DAQSession = app.deleteHandle(app.DAQSession);    
                app.DataAvailableLH = app.deleteHandle(app.DataAvailableLH);
                
                % Get the device information needed
                deviceID = app.DevicesInfo(deviceIndex).ID;
                vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                
                % Create a new DAQ session
                app.DAQSession = daq.createSession(vendor);
                measurementType = 'Voltage';
                addAnalogInputChannel(app.DAQSession, deviceID, [app.WrenchChannels app.EMGChannels], measurementType);
                
                % Set DAQ object sampling rate and time
                app.DAQSession.Rate = app.sRate;
                %app.DAQSession.DurationInSeconds = app.sTime;
                app.DAQSession.IsContinuous = true;
                app.DAQSession.NotifyWhenDataAvailableExceeds = 0.1*app.sRate; %CHECK THIS
                
                % Create listener for DataAvailable event and specify callback function
                app.DataAvailableLH = addlistener(app.DAQSession,'DataAvailable', ...
                    @(src,event) DataAvailableCallback(app, src, event));                              
                
                % reset FIFO buffer data
                app.WrenchFIFOBuffer = [];
                app.EMGFIFOBuffer = [];
                app.TimestampsFIFOBuffer = [];
                
                % start collecting data in background (continuous mode)
                startBackground(app.DAQSession); 
                app.isAcquiring = 1; % set app property to true
                
                % initialize plots
                app.WrenchLH = app.initializePlots(app.WrenchAxes, app.WrenchChannels+1, app.WrenchChannelNames);
                
                if app.collectEMG == 1 && ~isempty(app.EMGChannels)
                    app.EMGLH = app.initializePlots(app.EMGAxes, app.EMGChannels+1, app.EMGChannelNames);
                end
                
            else
                % If no device is selected
                uialert(app.TargetDAQUI,'Select a valid DAQ Device from the dropdown menu.','No DAQ Device Selected')
            end   
                 
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % disable/enable Buttons
            app.EnableDisableInitialization(1);
            
            % stop and clear DAQ session
            if ~isempty(app.DAQSession)
                stop(app.DAQSession);
                app.deleteHandle(app.DAQSession);
            end
            
            % set app property for acquiring data to false
            app.isAcquiring = 0; 
            
            % clear line handles to remove feedback 
            if ishandle(app.FigureHandle) % target window is open
               app.FBPyramidLH = app.clearLine(app.FBPyramidLH);
            end
            
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
            if ~isempty(app.subjID)
                
                setup = app.setupStruct();
                
                if ~isempty(app.datadir)
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.datadir '\' app.subjID '_Setup.mat']);
                else 
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.subjID '_Setup.mat']);
                end
                
                if fname ~= 0
                    save(fullfile(pname,fname),'setup');
                else
                    warndlg('Setup file was not saved','Save Setup')
                end
                
            else
                warndlg('Please fill the participant information form first','Save Setup');
            end           
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            
            if ~isempty(app.datadir)
                [fname,pname] = uigetfile([app.datadir '\*.mat'], 'Select the setup file (*.mat)');
            else 
                [fname,pname] = uigetfile([pwd '\*.mat'], 'Select the setup file (*.mat)');
            end
            
            if fname ~= 0 
                load(fullfile(pname,fname), 'setup');
                if exist('setup')
                    app.loadConfigUpdate(setup);                           
                end
            end
        end

        % Value changed function: ParticipantIDField
        function ParticipantIDFieldValueChanged(app, event)
            app.subjID = app.ParticipantIDField.Value;            
        end

        % Value changed function: ProtocolField
        function ProtocolFieldValueChanged(app, event)
            app.Protocol = app.ProtocolField.Value;            
        end

        % Value changed function: NotesField
        function NotesFieldValueChanged(app, event)
            app.Notes = app.NotesField.Value;            
        end

        % Value changed function: scanRateField
        function scanRateFieldValueChanged(app, event)
            app.sRate = app.scanRateField.Value;            
        end

        % Value changed function: sampleTimeField
        function sampleTimeFieldValueChanged(app, event)
            app.sTime = app.sampleTimeField.Value;            
        end

        % Value changed function: CollectEMGCheckBox
        function CollectEMGCheckBoxValueChanged(app, event)
            collectEMGbool = app.CollectEMGCheckBox.Value;
            
            % save state into property
            app.collectEMG = collectEMGbool;
            
            switch collectEMGbool
                
                case 0 % unchecked
                    
                    % stop collecting EMG
                    app.nEMGSpinner.Value = 0;
                    app.nEMGSpinner.Enable = 'off';
                    app.EMGChannels = [];
                    
                case 1 % checked
                    
                    % start collecting EMG signals                    
                    app.nEMGSpinner.Enable = 'on';
                    
            end
            
            % run value change callback
            app.nEMGSpinnerValueChanged()
                
            
        end

        % Cell edit callback: BonyLandmarkTable
        function BonyLandmarkTableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            
            
        end

        % Value changed function: nEMGSpinner
        function nEMGSpinnerValueChanged(app, event)
            
            % number of EMG channels to collect
            nEMG = app.nEMGSpinner.Value;           
            
            % set EMGChannels property to correct channels and initialize plots 
            if (nEMG > 0) && (app.collectEMG == 1)
                app.EMGChannels = app.EMGChannelsAvail(1:nEMG);                  
            else % nEMG == 0
                app.EMGChannels = [];                
            end 
            
            % delete previous line handles 
            app.EMGLH = app.deleteHandle(app.EMGLH);
            
            % update EMG axes and line handles
            app.EMGLH = app.initializePlots(app.EMGAxes, app.EMGChannels+1, app.EMGChannelNames);
            
        end

        % Close request function: TargetDAQUI
        function TargetDAQUICloseRequest(app, event)

            acquiring = ~isempty(app.DAQSession) && app.DAQSession.IsRunning;
            if acquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.TargetDAQUI, question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,acquiring));
                                  
            
        end

        % Value changed function: DAQDeviceDropDown
        function DAQDeviceDropDownValueChanged(app, event)
            
            app.daqDevice = app.DAQDeviceDropDown.Value - 1;                           

        end

        % Value changed function: ShowTargetCheckBox
        function ShowTargetCheckBoxValueChanged(app, event)
            % get current value 
            bool = app.ShowTargetCheckBox.Value;            
            
            if ishandle(app.FigureHandle) % target feedback window open
                switch bool                    
                    case 0 % unchecked: don't show target
                        app.TPyramidLH = app.deleteHandle(app.TPyramidLH);
                    case 1 % checked: show target
                        tvec = app.changeScale(app.TargetValues, app.WRanges, app.VisLim);
                        app.TPyramidLH = app.updatePyramid(app.TPyramidLH, tvec(1:3), tvec(4:6),'target');
                end                
            end
            
            % update app property for use in other functions
            app.drawtarget = bool;
           
        end

        % Button pushed function: AcquireDataButton
        function AcquireDataButtonValueChanged(app, event)
           
            % disable buttons and update status for acquisition
            app.EnableDisableAcquisition(0)
            
            % unique temporary file name and open handle to file for saving 
            app.TempFilename = tempname;
            app.TempFile = fopen(app.TempFilename, 'w');                   
            
            % set logdata flag to 1 to start saving data to file in data available callback function 
            app.logdata = 1;
            
        end

        % Button pushed function: CurrentDirectoryButton
        function CurrentDirectoryButtonPushed(app, event)
            app.datadir = uigetdir(cd, 'Select Directory to Save to');
            app.CurrentDirectoryTextArea.Value = app.datadir;
        end

        % Value changed function: TrialNumberField
        function TrialNumberFieldValueChanged(app, event)
            app.itrial = app.TrialNumberField.Value;            
        end

        % Cell edit callback: WrenchLimitsTable
        function WRangeValueChanged(app, event)
            app.WRanges = app.WrenchLimitsTable.Data;
        end

        % Cell edit callback: TargetAxesLimitsTable
        function TargetAxesLimitsTableCellEdit(app, event)
            newlim = app.TargetAxesLimitsTable.Data;
            app.VisLim = [newlim(1:3,:); deg2rad(newlim(4:6,:))];            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetDAQUI = uifigure;
            app.TargetDAQUI.Name = 'UI Figure';
            app.TargetDAQUI.CloseRequestFcn = createCallbackFcn(app, @TargetDAQUICloseRequest, true);
            app.TabGroup = uitabgroup(app.TargetDAQUI);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.DAQConfigurationPanel = uipanel(app.SetupTab);
            app.DAQConfigurationPanel.Title = 'DAQ Configuration';
            app.DAQDeviceDropDownLabel = uilabel(app.DAQConfigurationPanel);
            app.DAQDeviceDropDownLabel.Text = 'DAQ Device';
            app.DAQDeviceDropDown = uidropdown(app.DAQConfigurationPanel);
            app.DAQDeviceDropDown.Items = {'Select a Device'};
            app.DAQDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DAQDeviceDropDownValueChanged, true);
            app.DAQDeviceDropDown.Value = 'Select a Device';
            app.ScanRateLabel = uilabel(app.DAQConfigurationPanel);
            app.ScanRateLabel.Text = 'Scan Rate';
            app.scanRateField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.scanRateField.Limits = [0 Inf];
            app.scanRateField.ValueChangedFcn = createCallbackFcn(app, @scanRateFieldValueChanged, true);
            app.scanRateField.Value = 1000;
            app.SampleTimeLabel = uilabel(app.DAQConfigurationPanel);
            app.SampleTimeLabel.Text = 'Sample Time';
            app.sampleTimeField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.sampleTimeField.Limits = [0 Inf];
            app.sampleTimeField.ValueChangedFcn = createCallbackFcn(app, @sampleTimeFieldValueChanged, true);
            app.sampleTimeField.Value = 5;
            app.SubjectInformationPanel = uipanel(app.SetupTab);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.ParticipantIDEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ParticipantIDField.ValueChangedFcn = createCallbackFcn(app, @ParticipantIDFieldValueChanged, true);
            app.ProtocolEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ProtocolEditFieldLabel.Text = 'Protocol';
            app.ProtocolField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ProtocolField.ValueChangedFcn = createCallbackFcn(app, @ProtocolFieldValueChanged, true);
            app.NotesTextAreaLabel = uilabel(app.SubjectInformationPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesField = uitextarea(app.SubjectInformationPanel);
            app.NotesField.ValueChangedFcn = createCallbackFcn(app, @NotesFieldValueChanged, true);
            app.SaveConfigButton = uibutton(app.SetupTab, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.SetupTab, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.EMGOptionsPanel = uipanel(app.SetupTab);
            app.EMGOptionsPanel.Title = 'EMG Options';
            app.CollectEMGCheckBox = uicheckbox(app.EMGOptionsPanel);
            app.CollectEMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollectEMGCheckBoxValueChanged, true);
            app.CollectEMGCheckBox.Text = 'Collect EMG';
            app.ofEMGsSpinnerLabel = uilabel(app.EMGOptionsPanel);
            app.ofEMGsSpinnerLabel.Enable = 'off';
            app.ofEMGsSpinnerLabel.Text = '# of EMGs';
            app.nEMGSpinner = uispinner(app.EMGOptionsPanel);
            app.nEMGSpinner.Limits = [0 6];
            app.nEMGSpinner.ValueChangedFcn = createCallbackFcn(app, @nEMGSpinnerValueChanged, true);
            app.nEMGSpinner.Enable = 'off';
            app.InitializeDAQButton = uibutton(app.SetupTab, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.FileOptionsPanel = uipanel(app.SetupTab);
            app.FileOptionsPanel.Title = 'File Options';
            app.CurrentDirectoryButton = uibutton(app.FileOptionsPanel, 'push');
            app.CurrentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentDirectoryButtonPushed, true);
            app.CurrentDirectoryButton.Text = {'Current '; 'Directory'};
            app.CurrentDirectoryTextArea = uitextarea(app.FileOptionsPanel);
            app.basefilenameLabel = uilabel(app.FileOptionsPanel);
            app.basefilenameLabel.Text = 'base filename';
            app.basefilenameEditField = uieditfield(app.FileOptionsPanel, 'text');
            app.basefilenameEditField.Value = 'data_trial';
            app.TransformTab = uitab(app.TabGroup);
            app.TransformTab.Title = 'Transform';
            app.TransformTable = uitable(app.TransformTab);
            app.TransformTable.ColumnName = {'X'; 'Y'; 'Z'; 'X Angle'; 'Y Angle'; 'Z Angle'};
            app.TransformTable.RowName = {};
            app.TransformTable.ColumnEditable = true;
            app.TransformTableLabel = uilabel(app.TransformTab);
            app.TransformTableLabel.Text = 'Transform (Load Cell -> Head)';
            app.BonyLandmarkTable = uitable(app.TransformTab);
            app.BonyLandmarkTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.BonyLandmarkTable.RowName = {'LoadCell1'; 'LoadCell2'; 'LoadCell3'; 'Head1'; 'Head2'; 'Head3'; 'Torso1'; 'Torso2'; 'Torso3'};
            app.BonyLandmarkTable.ColumnEditable = true;
            app.BonyLandmarkTable.CellEditCallback = createCallbackFcn(app, @BonyLandmarkTableCellEdit, true);
            app.BonyLandmarkTableLabel = uilabel(app.TransformTab);
            app.BonyLandmarkTableLabel.Text = 'Bony Landmark Locations';
            app.SetTargetTab = uitab(app.TabGroup);
            app.SetTargetTab.Title = 'Set Target';
            app.TargetSettingsPanel = uipanel(app.SetTargetTab);
            app.TargetSettingsPanel.Title = 'Target Settings ';
            app.WrenchRangeLabel = uilabel(app.TargetSettingsPanel);
            app.WrenchRangeLabel.Text = 'Wrench Range';
            app.TargetLabel = uilabel(app.TargetSettingsPanel);
            app.TargetLabel.Text = 'Target';
            app.WrenchLimitsTable = uitable(app.TargetSettingsPanel);
            app.WrenchLimitsTable.ColumnName = {'low'; 'high'};
            app.WrenchLimitsTable.RowName = {'Fx (N)'; 'Fy (N)'; 'Fz (N)'; 'Mx (Nm)'; 'My (Nm)'; 'Mz (Nm)'};
            app.WrenchLimitsTable.ColumnEditable = true;
            app.WrenchLimitsTable.CellEditCallback = createCallbackFcn(app, @WRangeValueChanged, true);
            app.TargetTable = uitable(app.TargetSettingsPanel);
            app.TargetTable.ColumnName = {'Target'};
            app.TargetTable.RowName = {'Fx (N)'; 'Fy (N)'; 'Fz (N)'; 'Mx (Nm)'; 'My (Nm)'; 'Mz (Nm)'};
            app.TargetTable.ColumnEditable = true;
            app.TargetTable.CellEditCallback = createCallbackFcn(app, @TargetSetValueChanged, true);
            app.VisualRangeLabel = uilabel(app.TargetSettingsPanel);
            app.VisualRangeLabel.Text = 'Visual Range';
            app.TargetAxesLimitsTable = uitable(app.TargetSettingsPanel);
            app.TargetAxesLimitsTable.ColumnName = {'low'; 'high'};
            app.TargetAxesLimitsTable.RowName = {'X (pixels)'; 'Y (pixels)'; 'Z (size)'; 'TX (deg)'; 'TY (deg)'; 'TZ (deg)'};
            app.TargetAxesLimitsTable.ColumnEditable = true;
            app.TargetAxesLimitsTable.CellEditCallback = createCallbackFcn(app, @TargetAxesLimitsTableCellEdit, true);
            app.OpenTargetWindowButton = uibutton(app.SetTargetTab, 'state');
            app.OpenTargetWindowButton.ValueChangedFcn = createCallbackFcn(app, @OpenTargetWindowButtonValueChanged, true);
            app.OpenTargetWindowButton.Text = 'Open Target Window';
            app.ZeroLoadCellButton = uibutton(app.SetTargetTab, 'push');
            app.ZeroLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroLoadCellButtonPushed, true);
            app.ZeroLoadCellButton.Text = 'Zero Load Cell';
            app.ShowTargetCheckBox = uicheckbox(app.SetTargetTab);
            app.ShowTargetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTargetCheckBoxValueChanged, true);
            app.ShowTargetCheckBox.Enable = 'off';
            app.ShowTargetCheckBox.Text = 'Show Target';
            app.WrenchPlotsTab = uitab(app.TabGroup);
            app.WrenchPlotsTab.Title = 'WrenchPlots';
            app.WrenchAxes1 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes2 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes3 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes4 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes5 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes6 = uiaxes(app.WrenchPlotsTab);
            app.EMGPlotsTab = uitab(app.TabGroup);
            app.EMGPlotsTab.Title = 'EMGPlots';
            app.EMGAxes1 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes2 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes3 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes4 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes5 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes6 = uiaxes(app.EMGPlotsTab);
            app.StopButton = uibutton(app.TargetDAQUI, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.TrialEditFieldLabel = uilabel(app.TargetDAQUI);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialNumberField = uieditfield(app.TargetDAQUI, 'numeric');
            app.TrialNumberField.Limits = [0 Inf];
            app.TrialNumberField.ValueChangedFcn = createCallbackFcn(app, @TrialNumberFieldValueChanged, true);
            app.StatusLabel = uilabel(app.TargetDAQUI);
            app.StatusLabel.Text = 'Status: ';
            app.AcquireDataButton = uibutton(app.TargetDAQUI, 'push');
            app.AcquireDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDataButtonValueChanged, true);
            app.AcquireDataButton.Text = 'Acquire Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tDAQpy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetDAQUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetDAQUI)
        end
    end
end